classdef gui_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        TocnostLabel         matlab.ui.control.Label
        PredikcijaLabel      matlab.ui.control.Label
        SlikeEditField       matlab.ui.control.NumericEditField
        SlikeEditFieldLabel  matlab.ui.control.Label
        OdaberislikuButton   matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end


    properties (Access = private)
        trainedModel
        testImages
        testLabels
        testAccuracy % Description
    end


    methods (Access = private)
        function startupFcn(app)
            modelFile = fullfile(pwd,'trainedModel.mat');
            if ~isfile(modelFile)
                uialert(app.UIFigure,'Nije pronaden spremljeni model. Pokreni prvo trening.','Greska');
                return;
            end
            S = load(modelFile,'trainedModel');
            app.trainedModel = S.trainedModel;

            [allImages, allLabels] = load_dataset();
            numTotalImages = length(allImages);
            rng(1);
            shuffledIndices = randperm(numTotalImages);
            numTrain = round(0.7 * numTotalImages);
            testIndices = shuffledIndices(numTrain+1:end);

            app.testImages = allImages(testIndices);
            app.testLabels = allLabels(testIndices);
            app.testAccuracy = model_testing(app.trainedModel, app.testImages, app.testLabels);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OdaberislikuButton
        function OdaberislikuButtonPushed(app, event)
            S = load('trainedModel.mat', 'trainedModel');
            app.trainedModel = S.trainedModel;

            [file,path] = uigetfile('*.bmp','Odaberite sliku');
            if isequal(file,0)
                return;
            end

            imgPath = fullfile(path,file);
            pathParts = split(imgPath, filesep);

            actualPerson = str2double(pathParts{end-2});
            actualSide = pathParts{end-1};

            disp(actualPerson)
            disp(actualSide)
            img = imread(imgPath);

            imshow(img,'Parent',app.UIAxes);

            imgProcessed = image_preprocess(img);
            featureVector = feature_extract(imgProcessed);

            predictedLabel = predict(app.trainedModel, featureVector);
            actualImageName = erase(file, ".bmp");

            fullPrediction = sprintf('%03d-%s-%s', predictedLabel, actualSide, actualImageName);
            actualFullName = sprintf('%03d-%s-%s', actualPerson, actualSide, actualImageName);

            if predictedLabel == actualPerson
                resultText = 'tocno';
            else
                resultText = 'netocno';
            end

            app.PredikcijaLabel.Text = sprintf('Predikcija: %s', fullPrediction);
            app.TocnostLabel.Text = sprintf('Stvarno: %s | Rezultat: %s', actualFullName, resultText);

            [allImages, allLabels] = load_dataset();

            numTotalImages = length(allImages);
            rng(1);
            shuffledIndices = randperm(numTotalImages);

            numTrain = round(0.7 * numTotalImages);
            testIndices = shuffledIndices(numTrain+1:end);

            app.testImages = allImages(testIndices);
            app.testLabels = allLabels(testIndices);

            app.testAccuracy = model_testing(app.trainedModel, app.testImages, app.testLabels);
            app.SlikeEditField.Value = app.testAccuracy;
            disp("Predikcija je:");
            disp(predictedLabel);
            fprintf('Stvarna osoba: %03d\n', actualPerson);
            fprintf('Strana/prst: %s\n', actualSide);
            fprintf('Predikcija modela: %03d\n', predictedLabel);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Slike')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.OdaberislikuButton = uibutton(app.UIFigure, 'push');
            app.OdaberislikuButton.ButtonPushedFcn = createCallbackFcn(app, @OdaberislikuButtonPushed, true);
            app.OdaberislikuButton.Text = 'Odaberi sliku';
            app.SlikeEditFieldLabel = uilabel(app.UIFigure);
            app.SlikeEditFieldLabel.Text = 'Slike';
            app.SlikeEditField = uieditfield(app.UIFigure, 'numeric');
            app.PredikcijaLabel = uilabel(app.UIFigure);
            app.PredikcijaLabel.Text = 'Predikcija';
            app.TocnostLabel = uilabel(app.UIFigure);
            app.TocnostLabel.Text = 'Tocnost';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end